classdef interfata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Q2EditField       matlab.ui.control.NumericEditField
        Q2EditFieldLabel  matlab.ui.control.Label
        Q3EditField       matlab.ui.control.NumericEditField
        Q3EditFieldLabel  matlab.ui.control.Label
        Q1EditField       matlab.ui.control.NumericEditField
        Q1EditFieldLabel  matlab.ui.control.Label
        ResultsLabel      matlab.ui.control.Label
        q3Slider          matlab.ui.control.Slider
        Slider_3Label     matlab.ui.control.Label
        q2Slider          matlab.ui.control.Slider
        Slider_2Label     matlab.ui.control.Label
        q1Slider          matlab.ui.control.Slider
        SliderLabel       matlab.ui.control.Label
        BratroboticLabel  matlab.ui.control.Label
        Button            matlab.ui.control.Button
        torsorResults     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = robotSimulate(app, distance1, distance2, distance3, timp, pas)
            t=0:pas:timp;
            lungime = length(t);
            q1v = PID_Calcul(app, lungime, distance1, pas);
            q2v = PID_Calcul(app, lungime, distance2, pas);
            q3v = PID_Calcul(app, lungime, distance3, pas);
            %q1v=linspace(0, distance1, length(t));
            %q2v=linspace(0, distance2, length(t));
            %q3v=linspace(0, distance3, length(t));
            q1=[t; q1v]'; % z
            q2=[t; q2v]'; % x
            q3=[t; q3v]'; % translatie a 3 cupla

            assignin('base', "timp", timp)
            assignin('base', 'q1', q1);
            assignin('base', 'q2', q2);
            assignin('base', 'q3', q3);
            results = sim('brat_robotic.slx', 'SimulationMode', 'normal');
        end
        
        function results = PID_Calcul(app, lungime, distance, pas)
            results = zeros(1, lungime);
            Kp = 0.1;
            Ki = 2.5;
            Kd = 5;
            medie = 0;
            error = 0;
            pre_error = 0;
            derivative = 0;
            for i=2:lungime
                error = distance - results(i-1);
                Pout = Kp * error;
                medie = medie + error* pas;
                Iout = Ki * medie;
                derivative = (error - pre_error) / pas;
                Dout = Kd * derivative
                results(i) = Pout + Iout;
                pre_error = error;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
    cla(app.torsorResults);
    % Obține parametri din sliders
    q1 = app.q1Slider.Value * pi/180;
    q2 = app.q2Slider.Value * pi/180;
    q3 = app.q3Slider.Value;

    % Setează valorile în workspace-ul MATLAB
    

    % Rulează simularea
    try
        results = robotSimulate(app, q1, q2, q3, 10, 0.01);
    catch e
        uialert(app.UIFigure, e.message, 'Simulare Eșuată');
        return;
    end
    simulateResultsX = [results.x'];
    simulateResultsY = [results.y'];
    simulateResultsZ = [results.z'];
    hold(app.torsorResults, 'on')
        plot(app.torsorResults, simulateResultsX);
        plot(app.torsorResults, simulateResultsY);
        plot(app.torsorResults, simulateResultsZ);
    hold(app.torsorResults, 'off')


        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Q1EditField.Value=changingValue;
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Q2EditField.Value=changingValue;
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Q3EditField.Value=changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.torsorResults = uiaxes(app.UIFigure);
            title(app.torsorResults, 'Title')
            xlabel(app.torsorResults, 'X')
            ylabel(app.torsorResults, 'Y')
            zlabel(app.torsorResults, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.BratroboticLabel = uilabel(app.UIFigure);
            app.BratroboticLabel.Text = 'Brat robotic';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-180 180];
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = 'Slider';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-180 180];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = 'Slider';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-1 1];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.Q1EditFieldLabel = uilabel(app.UIFigure);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q1EditField.Editable = 'off';
            app.Q3EditFieldLabel = uilabel(app.UIFigure);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q3EditField.Editable = 'off';
            app.Q2EditFieldLabel = uilabel(app.UIFigure);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q2EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfata

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end